classdef Metadata < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Image3       matlab.ui.control.Image
        Image2       matlab.ui.control.Image
        imgShow      matlab.ui.control.Image
        tbMetadata   matlab.ui.control.Table
        btnExtract   matlab.ui.control.Button
        btnMetadata  matlab.ui.control.Button
    end

    
    properties (Access = private)
        data = {'Descripcion', ''; 
            'Origen', '';  
            'Imagen', ''; 
            'Fotografia Avanzada', ''; 
            'GPS', ''; 
            'Archivo', '';
            'Software', ''; 
            'Fecha de Creacion', ''; 
            'Fecha de Modificacion', '';};  % Datos para la tabla
        file % Archivo
        path % Ruta
        info
        img
        desc % Descripcion
        orig % Origen
        imagen % Imagen
        camara 
        gps
        arch 
        softw
        dateC
        dateM
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tbMetadata.Data = app.data;
        end

        % Button pushed function: btnMetadata
        function btnMetadataButtonPushed(app, event)
            app.info = imfinfo(strcat(app.path,app.file));
            app.desc = strcat('Resolution: ', string(app.info.Width), ' x ', string(app.info.Height), ' Scene Type: ', string(app.info.DigitalCamera.SceneType), ' Format: ', string(app.info.Format), ' Size: ', string(app.info.FileSize)); 
            app.orig = strcat('Make: ', string(app.info.Make), ' Model: ', string(app.info.Model), ' Software: ', string(app.info.Software));
            app.imagen = string(app.file);
            app.camara = strcat('Sensing method: ', string(app.info.DigitalCamera.SensingMethod), 'Exposure Mode: ', string(app.info.DigitalCamera.ExposureMode), 'White Balance: ', string(app.info.DigitalCamera.WhiteBalance));
            app.gps = strcat(strcat(string(app.info.GPSInfo.GPSLatitude(1)),'°',string(app.info.GPSInfo.GPSLatitude(2)),'´',string(app.info.GPSInfo.GPSLatitude(3)),'´´'), string(app.info.GPSInfo.GPSLatitudeRef),'+', strcat(string(app.info.GPSInfo.GPSLongitude(1)),'°',string(app.info.GPSInfo.GPSLongitude(2)),'´',string(app.info.GPSInfo.GPSLongitude(3)),'´´'), string(app.info.GPSInfo.GPSLatitudeRef));
            app.arch = strcat(string(app.info.Filename));
            app.softw = strcat(string(app.info.Software));
            app.dateC = strcat(string(app.info.DigitalCamera.DateTimeOriginal));
            app.dateM = strcat(string(app.info.FileModDate));
            app.data = {'Descripcion', char(app.desc); 
                'Origen', char(app.orig);  
                'Imagen', char(app.imagen); 
                'Fotografia Avanzada (Tiempo de exposición)', char(string(app.info.DigitalCamera.ExposureTime));
                'Fotografia Avanzada (Flash)', char(string(app.info.DigitalCamera.Flash)); 
                'Fotografia Avanzada (Método de detección)', char(string(app.info.DigitalCamera.SensingMethod)); 
                'Fotografia Avanzada (Modo de exposición)', char(string(app.info.DigitalCamera.ExposureMode)); 
                'Fotografia Avanzada (Balance de blancos)', char(string(app.info.DigitalCamera.WhiteBalance)); 
                'GPS', char(app.gps); 
                'Archivo', char(app.arch);
                'Software', char(app.softw); 
                'Fecha de Creacion', char(app.dateC); 
                'Fecha de Modificacion', char(app.dateM)};
            app.tbMetadata.Data = app.data;
        end

        % Button pushed function: btnExtract
        function btnExtractButtonPushed(app, event)
            [app.file, app.path] = uigetfile('*png; *.jpg; *.jpeg; *.HEIC', 'Select an file');
            app.img = imread(strcat(app.path,app.file));
            app.imgShow.ImageSource = strcat(app.path,app.file);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.btnMetadata = uibutton(app.UIFigure, 'push');
            app.btnMetadata.ButtonPushedFcn = createCallbackFcn(app, @btnMetadataButtonPushed, true);
            app.btnMetadata.Text = 'Metadata';
            app.btnExtract = uibutton(app.UIFigure, 'push');
            app.btnExtract.ButtonPushedFcn = createCallbackFcn(app, @btnExtractButtonPushed, true);
            app.btnExtract.Text = 'Extraer Archivo';
            app.tbMetadata = uitable(app.UIFigure);
            app.tbMetadata.ColumnName = {''};
            app.tbMetadata.RowName = {};
            app.tbMetadata.ColumnSortable = true;
            app.imgShow = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'utsjr_logo.gif');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'cicata_logo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Metadata

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end