classdef admin_view < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Image2_2         matlab.ui.control.Image
        Image4           matlab.ui.control.Image
        btnBack          matlab.ui.control.Button
        Panel            matlab.ui.container.Panel
        btnViewPDF       matlab.ui.control.Button
        userPanel        matlab.ui.container.Panel
        Image            matlab.ui.control.Image
        SignedinasLabel  matlab.ui.control.Label
        lblUser          matlab.ui.control.Label
        Image2           matlab.ui.control.Image
        btnDevice        matlab.ui.control.Button
        btnRegister      matlab.ui.control.Button
        SourcecameraidentificationbyHellingerdistanceLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        user % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, user)
            % Save the parameter user in the propertie app.user
            app.user = user;
            % Show the user in the label
            app.lblUser.Text = app.user;
        end

        % Button pushed function: btnBack
        function btnBackButtonPushed(app, event)
            % Return to login form
            login();
            % Close this form
            delete(app);
        end

        % Button pushed function: btnRegister
        function btnRegisterButtonPushed(app, event)
            % Open User_Register form and send the parameter app.user
            user_register(app.user);
            % Close this form
            delete(app);
        end

        % Button pushed function: btnDevice
        function btnDeviceButtonPushed(app, event)
            % Open Device_Register form and send the parameter app.user
            device_register(app.user);
            % Close this form
            delete(app);
        end

        % Button pushed function: btnViewPDF
        function btnViewPDFButtonPushed(app, event)
            try
                rslt = fetch(database('conn_scih', 'uqrpef8uvj0gk5k7', 'hS0C86ybuxlTFNVYSSto'), strcat("SELECT type FROM User WHERE username = '", app.user, "'"));
            catch e
                msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
            end
            % Open Device_Register form and send the parameter app.user
            view_pdf(app.user, rslt.type);
            % Close this form
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.SourcecameraidentificationbyHellingerdistanceLabel = uilabel(app.Panel);
            app.SourcecameraidentificationbyHellingerdistanceLabel.Text = {'Source camera identification'; '        by Hellinger distance'};
            app.btnRegister = uibutton(app.Panel, 'push');
            app.btnRegister.ButtonPushedFcn = createCallbackFcn(app, @btnRegisterButtonPushed, true);
            app.btnRegister.Text = 'Register user';
            app.btnDevice = uibutton(app.Panel, 'push');
            app.btnDevice.ButtonPushedFcn = createCallbackFcn(app, @btnDeviceButtonPushed, true);
            app.btnDevice.Text = 'Register device';
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'src', 'SCI-H.png');
            app.userPanel = uipanel(app.Panel);
            app.lblUser = uilabel(app.userPanel);
            app.lblUser.Text = 'User Admin';
            app.SignedinasLabel = uilabel(app.userPanel);
            app.SignedinasLabel.Text = 'Signed in as: ';
            app.Image = uiimage(app.userPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'src', 'icon-admin.png');
            app.btnViewPDF = uibutton(app.Panel, 'push');
            app.btnViewPDF.ButtonPushedFcn = createCallbackFcn(app, @btnViewPDFButtonPushed, true);
            app.btnViewPDF.Text = 'View HLD PDF';
            app.btnBack = uibutton(app.UIFigure, 'push');
            app.btnBack.ButtonPushedFcn = createCallbackFcn(app, @btnBackButtonPushed, true);
            app.btnBack.Text = 'Log Out';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'src', 'cicata_icon.png');
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'src', 'utsjr_icon.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = admin_view(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end