classdef basic_view < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SpinnerLabel          matlab.ui.control.Label
        Spinner               matlab.ui.control.Image
        MetadataLabel         matlab.ui.control.Label
        SelectImageLabel      matlab.ui.control.Label
        userPanel             matlab.ui.container.Panel
        Image_2               matlab.ui.control.Image
        SignedinasLabel       matlab.ui.control.Label
        lblUser               matlab.ui.control.Label
        Image3                matlab.ui.control.Image
        Image2                matlab.ui.control.Image
        Image                 matlab.ui.control.Image
        SourcecameraidentificationbyHellingerdistanceLabel  matlab.ui.control.Label
        ImagestoanalizeLabel  matlab.ui.control.Label
        tblImages             matlab.ui.control.Table
        tblMetadata           matlab.ui.control.Table
        btnOrigin             matlab.ui.control.Button
        txtCaptureDevice      matlab.ui.control.EditField
        btnInforme            matlab.ui.control.Button
        CapturedeviceLabel    matlab.ui.control.Label
        txtImageName          matlab.ui.control.EditField
        btnLoadImage          matlab.ui.control.Button
        ImageLabel            matlab.ui.control.Label
        btnLogout             matlab.ui.control.Button
    end

    
    properties (Access = private)
        conn % Connection DB
        user % Description
        idUser % Id user
        nameUser % Name of User
        rslt % Storage resultset of the images (id's)
        imageSelected % Storage resultset of image selected in table (id, name, analysis)
        metadata % Storage metadata of image
        HLDCalculated % This boolean is for recalculate HLD
    end
    
    methods (Access = private)
        
        % Function that obtains all the id's and filenames from a user
        function getDisputeImages(app)
            query = strcat("SELECT idQImage, file FROM DisputeImage WHERE idUser=",string(app.idUser));
            try
                app.rslt = fetch(app.conn, query);

                % Insert data in table
                app.tblImages.Data = app.rslt;
            catch e
                msgbox(strcat("Error ", char(e.message)), "Warning", "error");
            end
        end

        % Function that obtains all the metadata from the file and
        % inserts them into tbMetadata
        function getMetadata(app)
            queryMeta = strcat("SELECT file, path, des, orig, cam, adv_photo, gps, arch FROM DisputeImage ", ...
                                "WHERE idQImage = ", string(app.imageSelected.idQImage));
            disp(queryMeta);
            % Execute the query and get the results in app.metadata
            try
                app.metadata = fetch(app.conn, queryMeta);

                % Insert the values of app.metadata into tbMetadata
                app.tblMetadata.Data = rows2vars(app.metadata);
            catch e
                msgbox(strcat("Error", char(e.message)), "Warning", "error");
            end
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, user)
            % Import paths
            addpath(strcat(pwd,'\modules\CameraFingerprint'));
            addpath(strcat(pwd,'\modules\ImageCrop'));
            addpath(strcat(pwd,'\modules\PDF'));
            addpath(strcat(pwd,'\modules\HLD'));
            addpath(strcat(pwd,'\modules\Metadata'));
            addpath(strcat(pwd,'\modules\Report'));

            % Initialize general properties
            app.user = "basicUser";
            
            % Show the user in the lblUser
            app.lblUser.Text = app.user;
            
            % Initialize connection to DB
            app.conn = database('conn_scih', 'uqrpef8uvj0gk5k7', 'hS0C86ybuxlTFNVYSSto');

            % Get user's id
            query = strcat("SELECT idUser, name FROM User WHERE username = '", app.user,"'");
            try
                app.rslt = fetch(app.conn, query);

                % Set the user id property
                app.idUser = app.rslt.idUser;
                app.nameUser = app.rslt.name;
    
                % Update table fcn
                app.getDisputeImages();
            catch e
                msgbox(strcat("Error", char(e.message)), "Warning", "error");
            end
        end

        % Button pushed function: btnLogout
        function btnLogoutButtonPushed(app, event)
            login();
            delete(app);
        end

        % Cell selection callback: tblImages
        function tblImagesCellSelection(app, event)

            app.btnInforme.Enable = "off";
            
            % Erase value of capture device input text
            app.txtCaptureDevice.Value = "";

            % Erase values of table metadata
            app.tblMetadata.Data = {};

            % Create a new query for image data
            query = strcat("SELECT idQImage, file, HLDanalysis FROM DisputeImage WHERE idQImage = ", string(app.rslt.idQImage(event.Indices(1,1))));

            try
                app.imageSelected = fetch(app.conn, query);

                % Update values of name input text
                app.txtImageName.Value = string(app.imageSelected.file);
    
                app.btnOrigin.Text = "Origin";
                app.btnOrigin.Enable = "on";
                app.HLDCalculated = false;
                app.getMetadata();
            catch e
                msgbox(strcat("Error", char(e.message)), "Warning", "error");
            end
        end

        % Button pushed function: btnLoadImage
        function btnLoadImageButtonPushed(app, event)
            [file, path] = uigetfile('*png; *.jpg; *.jpeg; *.HEIC', 'Select One or More Files', 'MultiSelect', 'on');
            prog = uiprogressdlg(app.UIFigure, 'Title', 'Please wait...', 'Message', 'Loading Images');
            pause(.5)
            if iscell(file)
                perc = 1 / width(file);
                for i = 1:width(file)
                    prog.Value = perc * i;
                    prog.Message = strcat("Loading Image #", string(i), " / ", string(width(file)));
                    pause(.05)
                    % Move image to stage folder
                    copyfile(strcat(string(path),string(file(i))),"images\DisputeImage\");

                    % Metadata
                    meta = extractMetadata(string(path), string(file(i)));
                    
                    newDImage = table(app.idUser, string(file(i)), strcat("images\DisputeImage\"), string(meta.des), string(meta.orig), string(meta.cam), string(meta.adv_photo), string(meta.gps), string(meta.arch), ...
                                        'VariableNames', {'idUser' 'file' 'path' 'des' 'orig' 'cam' 'adv_photo' 'gps' 'arch'});
                    try
                        % Try write in database
                        sqlwrite(app.conn, 'DisputeImage', newDImage);
                    catch e
                        % Show a msgbox to show the error
                        msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
                    end
                end
                % Show a msgbox to confirm the write
                msgbox("Dispute Images register succesfully", "Information", "help");
            else
                if file ~= 0
                    prog.Value = .25;
                    prog.Message = 'Moving image';
                    pause(.1)
                    % Move image to stage folder
                    copyfile(strcat(string(path),string(file)),"images\DisputeImage\");
                    
                    prog.Value = .50;
                    prog.Message = 'Extracting metadata';
                    pause(.1)
                    % Metadata
                    meta = extractMetadata(string(path), string(file));

                    prog.Value = .75;
                    prog.Message = 'Creating statement';
                    pause(.1)
    
                    newDImage = table(app.idUser, string(file), strcat("images\DisputeImage\"), string(meta.des), string(meta.orig), string(meta.cam), string(meta.adv_photo), string(meta.gps), string(meta.arch), ...
                                    'VariableNames', {'idUser' 'file' 'path' 'des' 'orig' 'cam' 'adv_photo' 'gps' 'arch'});
                  
                    prog.Value = 1;
                    prog.Message = 'Writing in DB';
                    pause(.1)
                    try
                        % Try write in database
                        sqlwrite(app.conn, 'DisputeImage', newDImage);
                        % Show a msgbox to confirm the write
                        msgbox("Dispute Image register succesfully", "Information", "help");
                    catch e
                        % Show a msgbox to show the error
                        msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
                    end
                end
            end
            close(prog)
            app.getDisputeImages();
        end

        % Button pushed function: btnOrigin
        function btnOriginButtonPushed(app, event)
            % Deactivate table and buttons
            app.tblImages.Enable = "off";
            app.btnLoadImage.Enable = "off";
            app.btnOrigin.Enable = "off";
            app.btnInforme.Enable = "off";

            % Activate indicators
            app.Spinner.Visible = "on";
            app.SpinnerLabel.Text = "Processing... 1/4";
            app.SpinnerLabel.Visible = "on";

            % Check if selected image has been analized
            if app.imageSelected.HLDanalysis == false || app.HLDCalculated == true
                % HLD
                % Crop dispute images
                cropAndSaveImg(app.conn, app.imageSelected.idQImage, "dispute");
    
                % Extract PRNU from crops
                app.SpinnerLabel.Text = "Processing... 2/4";
                extractPRNU(app.conn, app.imageSelected.idQImage, "dispute");
    
                % Extract and save PDF from PRNU in each one
                app.SpinnerLabel.Text = "Processing... 3/4";
                extractPDF(app.conn, app.imageSelected.idQImage, "dispute");
    
                % Compare PDFs between reference and dispute
                app.SpinnerLabel.Text = "Processing... 4/4";
                extractAndSaveAreas(app.conn, app.imageSelected.idQImage, "dispute");
                calculateHLD(app.conn, app.imageSelected.idQImage);
                app.SpinnerLabel.Text = "Done";
            end
            
            % Obtain result of HLD highest coincidence
            try
                query = strcat("SELECT brand, model, percentage FROM HLD INNER JOIN Device ON HLD.idDevice = Device.idDevice WHERE idQImage = ",string(app.imageSelected.idQImage)," ORDER BY percentage DESC LIMIT 1");
                HLDdevice = fetch(app.conn, query);

                app.txtCaptureDevice.Value = strcat(string(HLDdevice.brand)," ",string(HLDdevice.model), " (", string(HLDdevice.percentage), "% coincidence)");
                
            catch e
                % Show a msgbox to show the error
                msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
            end
            
            % Deactivate indicators
            app.Spinner.Visible = "off";
            app.SpinnerLabel.Visible = "off";

            % Activate table and buttons
            app.tblImages.Enable = "on";
            app.btnLoadImage.Enable = "on";
            app.btnInforme.Enable = "on";
            app.btnOrigin.Text = "Recalculate HLD";
            app.btnOrigin.Enable = "on";
            app.HLDCalculated = true;
        end

        % Button pushed function: btnInforme
        function btnInformeButtonPushed(app, event)
            generateReport(app.conn, app.idUser, app.imageSelected.idQImage);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.btnLogout = uibutton(app.UIFigure, 'push');
            app.btnLogout.ButtonPushedFcn = createCallbackFcn(app, @btnLogoutButtonPushed, true);
            app.btnLogout.Text = 'Log Out';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image';
            app.btnLoadImage = uibutton(app.UIFigure, 'push');
            app.btnLoadImage.ButtonPushedFcn = createCallbackFcn(app, @btnLoadImageButtonPushed, true);
            app.btnLoadImage.Text = 'Load image';
            app.txtImageName = uieditfield(app.UIFigure, 'text');
            app.txtImageName.Editable = 'off';
            app.CapturedeviceLabel = uilabel(app.UIFigure);
            app.CapturedeviceLabel.Text = 'Capture device';
            app.btnInforme = uibutton(app.UIFigure, 'push');
            app.btnInforme.ButtonPushedFcn = createCallbackFcn(app, @btnInformeButtonPushed, true);
            app.btnInforme.Enable = 'off';
            app.btnInforme.Text = 'Generate report';
            app.txtCaptureDevice = uieditfield(app.UIFigure, 'text');
            app.txtCaptureDevice.Editable = 'off';
            app.btnOrigin = uibutton(app.UIFigure, 'push');
            app.btnOrigin.ButtonPushedFcn = createCallbackFcn(app, @btnOriginButtonPushed, true);
            app.btnOrigin.Enable = 'off';
            app.btnOrigin.Text = 'Origin';
            app.tblMetadata = uitable(app.UIFigure);
            app.tblMetadata.ColumnName = '';
            app.tblMetadata.RowName = {};
            app.tblMetadata.SelectionType = 'row';
            app.tblMetadata.Multiselect = 'off';
            app.tblImages = uitable(app.UIFigure);
            app.tblImages.ColumnName = '';
            app.tblImages.RowName = {};
            app.tblImages.SelectionType = 'row';
            app.tblImages.CellSelectionCallback = createCallbackFcn(app, @tblImagesCellSelection, true);
            app.tblImages.Multiselect = 'off';
            app.ImagestoanalizeLabel = uilabel(app.UIFigure);
            app.ImagestoanalizeLabel.Text = 'Images to analize';
            app.SourcecameraidentificationbyHellingerdistanceLabel = uilabel(app.UIFigure);
            app.SourcecameraidentificationbyHellingerdistanceLabel.Text = {'Source camera identification '; 'by Hellinger distance'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'src', 'SCI-H.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'src', 'utsjr_icon.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'src', 'cicata_icon.png');
            app.userPanel = uipanel(app.UIFigure);
            app.lblUser = uilabel(app.userPanel);
            app.lblUser.Text = 'User Admin';
            app.SignedinasLabel = uilabel(app.userPanel);
            app.SignedinasLabel.Text = 'Signed in as: ';
            app.Image_2 = uiimage(app.userPanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'src', 'icon-admin.png');
            app.SelectImageLabel = uilabel(app.UIFigure);
            app.SelectImageLabel.Text = 'Select Image';
            app.MetadataLabel = uilabel(app.UIFigure);
            app.MetadataLabel.Text = 'Metadata';
            app.Spinner = uiimage(app.UIFigure);
            app.Spinner.Visible = 'off';
            app.Spinner.ImageSource = fullfile(pathToMLAPP, 'src', 'Spinner.gif');
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Visible = 'off';
            app.SpinnerLabel.Text = 'Processing... 1/4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basic_view(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end