classdef device_register < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        imgAlert                      matlab.ui.control.Image
        lblAlert                      matlab.ui.control.Label
        imgWarning                    matlab.ui.control.Image
        lblWarning                    matlab.ui.control.Label
        Image5                        matlab.ui.control.Image
        Image4                        matlab.ui.control.Image
        Image3                        matlab.ui.control.Image
        TabGroup                      matlab.ui.container.TabGroup
        DevicesTab                    matlab.ui.container.Tab
        userPanel                     matlab.ui.container.Panel
        Image                         matlab.ui.control.Image
        SignedinasLabel               matlab.ui.control.Label
        lblUser                       matlab.ui.control.Label
        btnBackDevice                 matlab.ui.control.Button
        Panel_7                       matlab.ui.container.Panel
        swtGlobalDevices              matlab.ui.control.Switch
        ShowglobaldevicesSwitchLabel  matlab.ui.control.Label
        btnDeleteDevice               matlab.ui.control.Button
        ChooseadeviceLabel            matlab.ui.control.Label
        tbDevices                     matlab.ui.control.Table
        Panel_6                       matlab.ui.container.Panel
        CapturedeviceLabel            matlab.ui.control.Label
        btnRegister                   matlab.ui.control.Button
        txtModel                      matlab.ui.control.EditField
        ModelEditFieldLabel           matlab.ui.control.Label
        txtBrand                      matlab.ui.control.EditField
        BrandEditFieldLabel           matlab.ui.control.Label
        ImagesTab                     matlab.ui.container.Tab
        Spinner                       matlab.ui.control.Image
        SpinnerLabel                  matlab.ui.control.Label
        dpdDevice                     matlab.ui.control.DropDown
        DeviceDropDownLabel           matlab.ui.control.Label
        userPanel_2                   matlab.ui.container.Panel
        Image_2                       matlab.ui.control.Image
        SignedinasLabel_2             matlab.ui.control.Label
        lblUser_2                     matlab.ui.control.Label
        btnHLDImage                   matlab.ui.control.Button
        btnBackImage                  matlab.ui.control.Button
        Panel_11                      matlab.ui.container.Panel
        MetadataLabel                 matlab.ui.control.Label
        tbMetadata                    matlab.ui.control.Table
        Panel_10                      matlab.ui.container.Panel
        SourceImageLabel              matlab.ui.control.Label
        imgSource                     matlab.ui.control.Image
        btnLoadImage                  matlab.ui.control.Button
        Panel_9                       matlab.ui.container.Panel
        swtGlobalFiles                matlab.ui.control.Switch
        ShowglobalfilesLabel          matlab.ui.control.Label
        ManageimagesLabel             matlab.ui.control.Label
        btnDeleteImage                matlab.ui.control.Button
        tbImages                      matlab.ui.control.Table
    end

    properties (Access = private)
        user % User
        idUser % User id

        conn % Connection
        
        devices % Result of devices
        rImages % Result of images

        idDSelected % Id of the selected device
        idISelected % Id of the selected image
        dpdIdSelected % Id of the dropdown image selected
        
        queryGetDevice % Statement to get Devices
        queryGetRImage % Statement to get Reference Images
        queryGetMetadata % Statement to get Metadata
    end

    methods (Access = private)
        % Function that obtains all the devices from the database and
        % inserts them into tbDevices
        function getDevices(app)
            % Execute the query and get the results in app.devices
            app.devices = fetch(app.conn, app.queryGetDevice);
            % Insert the values of app.devices into tbDevices
            app.tbDevices.Data = app.devices;
            % This cycle repeats the height of the app.device 
            for i = 1:height(app.devices) 
                % Insert Items to dropdown dpdDevice
                app.dpdDevice.Items = strcat(string(app.devices.idDevice.'), ".- ", string(app.devices.brand.'), " ", string(app.devices.model.'));
            end
        end

        % Function that obtains all the Reference Images from the database 
        % and inserts them into tbImages
        function getRImages(app)
            % Execute the query and get the results in app.rImages
            app.rImages = fetch(app.conn, app.queryGetRImage);
            % Insert the values of app.rImages into tbImages
            app.tbImages.Data = app.rImages;
        end

        % Function that obtains all the metadata from the file and
        % inserts them into tbMetadata
        function getMetadata(app)
            % Execute the query and get the results in app.metadata
            metadata = fetch(app.conn, app.queryGetMetadata);
            % Insert the values of app.metadata into tbMetadata
            app.tbMetadata.Data = rows2vars(metadata).Var1;
        end

        % Function than validate the number of Reference Images register in
        % the database and show an alert
        function validateImgCount(app)
            % Get the highest values of img_count
            frst = app.devices.img_count(1);
            scnd = app.devices.img_count(2);
            % Validate if the highest value is more than 30 imgs
            if frst >= 30
                % Validate if the scnd highest value is more than 30 imgs
                if scnd >= 30
                    % Hide the alerts and warnings
                    app.lblAlert.Visible = "off";
                    app.lblWarning.Visible = "off";
                    app.imgAlert.Visible = "off";
                    app.imgWarning.Visible = "off";
                else
                    % Validate if the scnd highest value is more than 30 imgs
                    if scnd >= 20
                        % Show the alerts and hide the warnings
                        app.lblAlert.Visible = "on";
                        app.lblWarning.Visible = "off";
                        app.imgAlert.Visible = "on";
                        app.imgWarning.Visible = "off";
                    else
                        % Hide the alerts and show the warnings
                        app.lblAlert.Visible = "off";
                        app.lblWarning.Visible = "on";
                        app.imgAlert.Visible = "off";
                        app.imgWarning.Visible = "on";
                    end
                end
            else
                % Validate if the highest value is more than 20 imgs
                if frst >= 20
                    % Validate if the scnd highest value is more than 20 imgs
                    if scnd >= 20
                        % Show the alerts and hide the warnings
                        app.lblAlert.Visible = "on";
                        app.lblWarning.Visible = "off";
                        app.imgAlert.Visible = "on";
                        app.imgWarning.Visible = "off";
                    else
                        % Hide the alerts and show the warnings
                        app.lblAlert.Visible = "off";
                        app.lblWarning.Visible = "on";
                        app.imgAlert.Visible = "off";
                        app.imgWarning.Visible = "on";
                    end
                else
                    % Hide the alerts and show the warnings
                    app.lblAlert.Visible = "off";
                    app.lblWarning.Visible = "on";
                    app.imgAlert.Visible = "off";
                    app.imgWarning.Visible = "on";
                end
            end
        end

        % Function to update the image count in database 
        function updateImgCount(app)
            % Prepare the statement to get the image count to selected device 
            query = strcat("SELECT img_count FROM Device ", ... 
                        "WHERE idDevice = ", string(app.dpdIdSelected));
            % Try get the image count of selected device
            try
                rslt = fetch(app.conn, query);
            catch e
                msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
            end
            % Convert the image count to int and add one
            count = int32(rslt.img_count) + 1;
            % Prepare the statement to update the image count of selected
            % device
            query = strcat("UPDATE Device SET img_count = ", string(count), ...
                        " WHERE idDevice = ", string(app.dpdIdSelected));
            % Try update the image count
            try
                rslt = fetch(app.conn, query);
            catch e
                msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
            end
            % Show devices
            app.getDevices();
            % Validate the number of imgCount
            app.validateImgCount();
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, user)
            % Get user submission by previous page
            app.user = "root";
            % Show the user in all lblUser
            app.lblUser.Text = app.user;
            app.lblUser_2.Text = app.user;
            % Create the connection with database
            app.conn = database('conn_scih', 'uqrpef8uvj0gk5k7', 'hS0C86ybuxlTFNVYSSto');
            % Prepare the statement with the username to get idUser
            query = strcat("SELECT idUser FROM User ", ...
                                    "WHERE username = '", app.user, "'");
            % Execute the query and get the results in app.idUser
            app.idUser = fetch(app.conn, query);
            % Prepare the statement with the idUser to only get User
            % Devices and order by img_count to get the highest img_count
            % value in top
            app.queryGetDevice = strcat("SELECT idDevice, brand, model, img_count, username FROM Device JOIN User ON User.idUser = Device.idUser ", ...
                                    "WHERE Device.idUser = ", string(app.idUser.idUser), " ORDER BY img_count DESC");
            % Call the function getDevices
            app.getDevices();
            % Set the id selected from the dropdown in the first id device
            % to show in the dropdown later
            app.dpdIdSelected = app.devices.idDevice(1);
            % Prepare the statement with the idUser to only get User
            % Reference Images
            app.queryGetRImage = strcat("SELECT idRImage, file, username FROM ReferenceImage JOIN User on User.idUser = ReferenceImage.idUser " + ...
                                        "WHERE ReferenceImage.idUser = ", string(app.idUser.idUser));
            % Call the function getRImages
            app.getRImages();
            % Import paths
            addpath(strcat(pwd,'\modules\CameraFingerprint'));
            addpath(strcat(pwd,'\modules\CameraFingerprint\Funciones'));
            addpath(strcat(pwd,'\modules\CameraFingerprint\FuncionesCompiladas'));
            addpath(strcat(pwd,'\modules\CameraFingerprint\Functions'));
            addpath(strcat(pwd,'\modules\CameraFingerprint\Filter'));
            addpath(strcat(pwd,'\modules\ImageCrop'));
            addpath(strcat(pwd,'\modules\PDF'));
            addpath(strcat(pwd,'\modules\HLD'));
            addpath(strcat(pwd,'\modules\Metadata'));
            addpath(strcat(pwd,'\modules\Nomenclature'));
            addpath(strcat(pwd,'\modules\Hash'));
            addpath(strcat(pwd,'\modules\Report'));
            addpath(strcat(pwd,'\modules\Substr'));
            % Validate the number of imgCount
            app.validateImgCount();
        end

        % Cell selection callback: tbDevices
        function tbDevicesCellSelection(app, event)
            % Get the selected index and with that get the id from the
            % app.devices object with the previously obtained index
            app.idDSelected = app.devices.idDevice(event.Indices(1));
        end

        % Button pushed function: btnRegister
        function btnRegisterButtonPushed(app, event)
            % Get brand of txtBrand
            brand = app.txtBrand.Value;
            % Get model of txtModel
            model = app.txtModel.Value;
            % Validate if the brand and model aren't empty
            if brand ~= ""
                if model ~= ""
                    % The try tries to execute all the instructions, in case of
                    % an error it exit and shows the error obtaining it with the
                    % catch
                    try 
                        % Show a msgbox to confirm the write
                        msgbox("Device register succesfull", "Information", "help");
                        % Clean the TextBoxs
                        app.txtBrand.Value = "";
                        app.txtModel.Value = "";
                        % Create the folder nomenclature
                        carp = Nomenclature(brand, model);
                        % Obtains all directory of this path
                        dirRlst = dir(strcat("images\ReferenceImage\", string(carp)));
                        % Obtains the height and subtract 2 because dir
                        % returns in the first 2 rows a dot, two dots
                        % and after all directories
                        model_count = height(dirRlst) - 2;

                        % Validate if the directory is empty
                        if model_count < 0 
                            % If not exists create the dir and established 
                            % the model_count in 1
                            mkdir(strcat("images\ReferenceImage\", string(carp)));
                            model_count = 1;
                        else 
                            % IMPORTANT: This function only can create 10 
                            % dir's

                            % If enter in this case its because we have
                            % twins and we need to create a new dir with
                            % the same nomenclature but they have a 
                            % different id with "_X"

                            % Split the last dir by "_"
                            l = strsplit(dirRlst(height(dirRlst)).name, "_");
                            % Get the num of dirs
                            n_dir = l(2);
                            % Add one to model_count
                            model_count = str2num(string(n_dir)) + 1;
                        end

                        % Create the route variable to use to create all
                        % dirs
                        route = strcat("images\ReferenceImage\", string(carp), "\", string(carp), "_", string(model_count), "\");
                        % Create the main dir
                        mkdir(route);
                        % Create the Crops dir in main dir
                        mkdir(strcat(route, "\Crops"));
                        % Create the PRNU dir in main dir
                        mkdir(strcat(route, "\PRNU"));
                        % Create the PDF dir in main dir
                        mkdir(strcat(route, "\PDF"));
                        % Create the HLD dir in main dir
                        mkdir(strcat(route, "\HLD"));

                        % Create a new table to insert a new row in Device 
                        % table in the database
                        newDevice = table(app.idUser.idUser, string(brand), string(model), 0, string(route), str2num(string(model_count)), ...
                                        'VariableNames', {'idUser', 'brand', 'model', 'img_count', 'route', 'model_count'});
                        % Try write in database
                        sqlwrite(app.conn, 'Device', newDevice);
                    catch e
                        % Show a msgbox to show the error
                        msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
                    end
                    % Call the function getDevices
                    app.getDevices();
                else
                    msgbox("Please fill the Model", "Warning", "warn");
                end
            else
                msgbox("Please fill the Brand", "Warning", "warn");
            end
        end

        % Button pushed function: btnDeleteDevice
        function btnDeleteDeviceButtonPushed(app, event)
            % Prepare the statement with the idDevice to delete an device
            query = strcat("DELETE FROM Device ", ...
                                    "WHERE idDevice = ", string(app.idDSelected));
             % The try tries to execute all the instructions, in case of
            % an error it exit and shows the error obtaining it with the
            % catch
            try
                % Execute the query and get the results in app.devices
                app.devices = fetch(app.conn, query);
            catch e
                % Show a msgbox to show the error
                msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
            end
            % Call the function getDevices 
            app.getDevices();
        end

        % Callback function: btnLoadImage, imgSource
        function btnLoadImageButtonPushed(app, event)
            % Function than obtains the file and the path to images loaded
            [file, path] = uigetfile('*.jpg;', 'Select One or More Files', 'MultiSelect', 'on');
            prog = uiprogressdlg(app.UIFigure, 'Title', 'Please wait...', 'Message', 'Loading Images');
            pause(.5)
            % Validate if is only one file or more files
            if iscell(file)
                perc = 1 / width(file);
                %% More files
                for i = 1:width(file)
                    % Activate indicators
                    prog.Value = perc * i;
                    prog.Message = strcat("Loading Image #", string(i), " / ", string(width(file)));
                    pause(.05)
                    
                    % Extract Metadata
                    meta = extractMetadata(string(path), string(file(i)));
                    
                    % Create the query to get info of the Device selected
                    query = strcat("SELECT brand, model, route, model_count, img_count FROM Device WHERE idDevice = ", string(app.dpdIdSelected));
                    try
                        % Get the result and save only rute and img_count
                        % in variables
                        rslt = fetch(app.conn, query);
                        route = rslt.route;
                        img_count = rslt.img_count;
                    catch e 
                        msgbox(strcat("Error en la BD: ", char(e.message)), "Warning", "error");
                    end

                    % Split the route by "\"
                    carpEsp = strsplit(string(route), "\");
                    % Get penultimate element of the split than contains
                    % the nomenclature
                    lastCarp = carpEsp(length(carpEsp) - 1);
                    % Add one to img_count
                    imCount = str2num(string(img_count)) + 1;
                    % Move image to folder 
                    copyfile(strcat(string(path), string(file(i))), strcat(string(route), string(lastCarp), "_(", string(imCount), ").jpg"));
                    
                    % Create a table to write in database with all elements
                    newRImage = table(str2num(string(app.dpdIdSelected)), str2num(string(app.idUser.idUser)), strcat(string(lastCarp), "_(", string(imCount), ").jpg"), string(route), string(meta.des), string(meta.orig), string(meta.cam), string(meta.adv_photo), string(meta.gps), string(meta.arch), ...
                                    'VariableNames', {'idDevice' 'idUser' 'file' 'path' 'des' 'orig' 'cam' 'adv_photo' 'gps' 'arch'});
                    try
                        % Try write in database
                        sqlwrite(app.conn, 'ReferenceImage', newRImage);
                        % Update the validation to show the corrects messages
                        app.updateImgCount();
                    catch e
                        % Show a msgbox to show the error
                        msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
                    end
                end
                % Show a msgbox to show a msg successfully
                msgbox("Reference Images register succesfully", "Information", "help");
                
                % Dissable indicators
                app.Spinner.Visible = "off";
                app.SpinnerLabel.Text = "Loaded";
                app.SpinnerLabel.Visible = "on";
            else
                % Validate if the user load at least one file
                if file ~= 0
                    %% One file
                    % Extract Metadata
                    prog.Value = .25;
                    prog.Message = 'Extracting Metadata';
                    pause(.1)
                    
                    meta = extractMetadata(string(path), string(file));
                    
                    prog.Value = .50;
                    prog.Message = 'Getting device info';
                    pause(.1)

                    % Create the query to get info of the Device selected
                    query = strcat("SELECT brand, model, route, model_count, img_count FROM Device WHERE idDevice = ", string(app.dpdIdSelected));
                    try
                        % Get the result and save only rute and img_count
                        % in variables
                        rslt = fetch(app.conn, query);
                        route = rslt.route;
                        img_count = rslt.img_count;
                    catch e 
                        msgbox(strcat("Error en la BD: ", char(e.message)), "Warning", "error");
                    end
    
                    prog.Value = .75;
                    prog.Message = 'Copying File';
                    pause(.1)
                    % Split the route by "\"
                    carpEsp = strsplit(string(route), "\");
                    % Get penultimate element of the split than contains
                    % the nomenclature
                    lastCarp = carpEsp(length(carpEsp) - 1);
                    % Add one to img_count
                    imCount = str2num(string(img_count)) + 1;
                    % Move image to folder 
                    copyfile(strcat(string(path), string(file)), strcat(string(route), "\", string(lastCarp), "_(", string(imCount), ").jpg"));

                    prog.Value = 1;
                    prog.Message = 'Writting in DB';
                    pause(.1)
                    
                    % Create a table to write in database with all elements
                    newRImage = table(str2num(string(app.dpdIdSelected)), str2num(string(app.idUser.idUser)), strcat(string(lastCarp), "_(", string(imCount), ").jpg"), string(route), string(meta.des), string(meta.orig), string(meta.cam), string(meta.adv_photo), string(meta.gps), string(meta.arch), ...
                                    'VariableNames', {'idDevice' 'idUser' 'file' 'path' 'des' 'orig' 'cam' 'adv_photo' 'gps' 'arch'});
                    try
                        % Try write in database
                        sqlwrite(app.conn, 'ReferenceImage', newRImage);
                        app.updateImgCount();
                        % Show a msgbox to confirm the write
                        msgbox("Reference Image register succesfully", "Information", "help");
                    catch e
                        % Show a msgbox to show the error
                        msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
                    end
                end
            end
            close(prog)
            % Get the references Image
            app.getRImages();
        end

        % Button pushed function: btnBackDevice, btnBackImage
        function btnBackDeviceButtonPushed(app, event)
            % Return to admin_view form and send the parameter app.user
            admin_view(app.user);
            % Close this form
            delete(app);
        end

        % Value changed function: swtGlobalDevices
        function swtGlobalDevicesValueChanged(app, event)
            % Check the switch Global Devices value
            if string(app.swtGlobalDevices.Value) == "On"
                % Prepare the statement to get all User Devices and order 
                % by img_count to get the highest img_count value in top
                app.queryGetDevice = strcat("SELECT idDevice, brand, model, img_count, username FROM Device JOIN User ON User.idUser = Device.idUser ORDER BY img_count DESC");
            else
                % Prepare the statement with the idUser to only get User
                % Devices and order by img_count to get the highest 
                % img_count value in top
                app.queryGetDevice = strcat("SELECT idDevice, brand, model, img_count, username FROM Device JOIN User ON User.idUser = Device.idUser ", ...
                                    "WHERE Device.idUser = ", string(app.idUser.idUser), " ORDER BY img_count DESC");
            end    
            % Get Devices
            app.getDevices();
        end

        % Value changed function: dpdDevice
        function dpdDeviceValueChanged(app, event)
            % Split the value in the dot and save in temp
            temp = split(app.dpdDevice.Value, ".");
            % Only save the first part of temp and save in property
            % dpdIdSelected
            app.dpdIdSelected = str2double(string(temp(1)));
        end

        % Value changed function: swtGlobalFiles
        function swtGlobalFilesValueChanged(app, event)
            % Check the switch Global Files value
            if string(app.swtGlobalFiles.Value) == "On"
                % Prepare the statement to get all User Reference Images
                app.queryGetRImage = strcat("SELECT idRImage, file, username FROM ReferenceImage JOIN User on User.idUser = ReferenceImage.idUser");
            else
                % Prepare the statement with the idUser to only get User
                % Reference Images
                app.queryGetRImage = strcat("SELECT idRImage, file, username FROM ReferenceImage JOIN User on User.idUser = ReferenceImage.idUser ", ...
                                    "WHERE ReferenceImage.idUser = ", string(app.idUser.idUser));
            end    
            % Get References Images
            app.getRImages();
        end

        % Cell selection callback: tbImages
        function tbImagesCellSelection(app, event)
            % Get row selected of the table and only select the id
            app.idISelected = app.rImages.idRImage(event.Indices(1));
            % Prepare the statement to get path and file of the image by
            % the ID
            query = strcat('SELECT path, file FROM ReferenceImage WHERE idRImage = ', string(app.idISelected));
            try
                rslt = fetch(app.conn, query);
            catch e
                msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
            end
            
            % Show the image selected in the ImageSource
            app.imgSource.ImageSource = strcat(string(rslt.path), string(rslt.file));
            % Prepare the statement with the idRImage to get Metadata from
            % image with these id
            app.queryGetMetadata = strcat("SELECT file, path, des, orig, cam, adv_photo, gps, arch FROM ReferenceImage ", ...
                                "WHERE idRImage = ", string(app.idISelected));
            % Get metadata
            app.getMetadata();
        end

        % Button pushed function: btnHLDImage
        function btnHLDImageButtonPushed(app, event)
           

            % Activate indicators
            prog = uiprogressdlg(app.UIFigure, 'Title', 'Please wait...', 'Message', 'Loading HLD');
            pause(.5)
          
            % HLD
            % Crop dispute images
            prog.Value = .25;
            prog.Message = 'Processing... 1/4';
            pause(.25)
            cropAndSaveImg(app.conn, 1, "reference");

            % Extract PRNU from crops
            prog.Value = .50;
            prog.Message = 'Processing... 2/4';
            pause(.25)
            extractPRNU(app.conn, 1, "reference");

            % Extract and save PDF from PRNU in each one
            prog.Value = .75;
            prog.Message = 'Processing... 3/4';
            pause(.25)
            extractPDF(app.conn, 1, "reference");

            % Compare PDFs between reference and dispute
            prog.Value = 1;
            prog.Message = 'Processing... 4/4';
            pause(.25)
            extractAndSaveAreas(app.conn, 1, "reference");
            HLDresult = calculateHLD(app.conn, 1);

            close(prog)
        end

        % Button pushed function: btnDeleteImage
        function btnDeleteImageButtonPushed(app, event)
            % Prepare the statement with the idDevice to delete an
            % Reference Image
            query = strcat("DELETE FROM ReferenceImage ", ...
                                    "WHERE idRImage = ", string(app.idISelected));
             % The try tries to execute all the instructions, in case of
            % an error it exit and shows the error obtaining it with the
            % catch
            try
                % Execute the query and get the results in app.devices
                rslt = fetch(app.conn, query);
            catch e
                % Show a msgbox to show the error
                msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
            end
            % Call the function getDevices 
            app.getRImages();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DevicesTab = uitab(app.TabGroup);
            app.DevicesTab.Title = 'Devices';
            app.Panel_6 = uipanel(app.DevicesTab);
            app.BrandEditFieldLabel = uilabel(app.Panel_6);
            app.BrandEditFieldLabel.Text = 'Brand';
            app.txtBrand = uieditfield(app.Panel_6, 'text');
            app.ModelEditFieldLabel = uilabel(app.Panel_6);
            app.ModelEditFieldLabel.Text = 'Model';
            app.txtModel = uieditfield(app.Panel_6, 'text');
            app.btnRegister = uibutton(app.Panel_6, 'push');
            app.btnRegister.ButtonPushedFcn = createCallbackFcn(app, @btnRegisterButtonPushed, true);
            app.btnRegister.Text = 'Register';
            app.CapturedeviceLabel = uilabel(app.Panel_6);
            app.CapturedeviceLabel.Text = 'Capture device';
            app.Panel_7 = uipanel(app.DevicesTab);
            app.tbDevices = uitable(app.Panel_7);
            app.tbDevices.ColumnName = {'idDevice'; 'Brand'; 'Model'; 'Image #'; 'User'};
            app.tbDevices.RowName = {};
            app.tbDevices.CellSelectionCallback = createCallbackFcn(app, @tbDevicesCellSelection, true);
            app.ChooseadeviceLabel = uilabel(app.Panel_7);
            app.ChooseadeviceLabel.Text = 'Choose a device';
            app.btnDeleteDevice = uibutton(app.Panel_7, 'push');
            app.btnDeleteDevice.ButtonPushedFcn = createCallbackFcn(app, @btnDeleteDeviceButtonPushed, true);
            app.btnDeleteDevice.Text = 'Delete';
            app.ShowglobaldevicesSwitchLabel = uilabel(app.Panel_7);
            app.ShowglobaldevicesSwitchLabel.Text = 'Show global devices';
            app.swtGlobalDevices = uiswitch(app.Panel_7, 'slider');
            app.swtGlobalDevices.ValueChangedFcn = createCallbackFcn(app, @swtGlobalDevicesValueChanged, true);
            app.btnBackDevice = uibutton(app.DevicesTab, 'push');
            app.btnBackDevice.ButtonPushedFcn = createCallbackFcn(app, @btnBackDeviceButtonPushed, true);
            app.btnBackDevice.Text = 'Back';
            app.userPanel = uipanel(app.DevicesTab);
            app.lblUser = uilabel(app.userPanel);
            app.lblUser.Text = 'User Admin';
            app.SignedinasLabel = uilabel(app.userPanel);
            app.SignedinasLabel.Text = 'Signed in as: ';
            app.Image = uiimage(app.userPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'src', 'icon-admin.png');
            app.ImagesTab = uitab(app.TabGroup);
            app.ImagesTab.Title = 'Images';
            app.Panel_9 = uipanel(app.ImagesTab);
            app.tbImages = uitable(app.Panel_9);
            app.tbImages.ColumnName = {'idRImage'; 'File'; 'User'};
            app.tbImages.RowName = {};
            app.tbImages.CellSelectionCallback = createCallbackFcn(app, @tbImagesCellSelection, true);
            app.tbImages.Multiselect = 'off';
            app.btnDeleteImage = uibutton(app.Panel_9, 'push');
            app.btnDeleteImage.ButtonPushedFcn = createCallbackFcn(app, @btnDeleteImageButtonPushed, true);
            app.btnDeleteImage.Text = 'Delete';
            app.ManageimagesLabel = uilabel(app.Panel_9);
            app.ManageimagesLabel.Text = 'Manage images';
            app.ShowglobalfilesLabel = uilabel(app.Panel_9);
            app.ShowglobalfilesLabel.Text = 'Show global files';
            app.swtGlobalFiles = uiswitch(app.Panel_9, 'slider');
            app.swtGlobalFiles.ValueChangedFcn = createCallbackFcn(app, @swtGlobalFilesValueChanged, true);
            app.Panel_10 = uipanel(app.ImagesTab);
            app.btnLoadImage = uibutton(app.Panel_10, 'push');
            app.btnLoadImage.ButtonPushedFcn = createCallbackFcn(app, @btnLoadImageButtonPushed, true);
            app.btnLoadImage.Text = 'Load Image';
            app.imgSource = uiimage(app.Panel_10);
            app.imgSource.ImageClickedFcn = createCallbackFcn(app, @btnLoadImageButtonPushed, true);
            app.SourceImageLabel = uilabel(app.Panel_10);
            app.SourceImageLabel.Text = 'Source Image';
            app.Panel_11 = uipanel(app.ImagesTab);
            app.tbMetadata = uitable(app.Panel_11);
            app.tbMetadata.ColumnName = {'Data'};
            app.tbMetadata.RowName = {'''Description'';'; '''Source'';'; '''Image'';'; '''Camera'';'; '''Advance photo'';'; '''GPS'';'; '''File'';'};
            app.tbMetadata.Multiselect = 'off';
            app.MetadataLabel = uilabel(app.Panel_11);
            app.MetadataLabel.Text = 'Metadata';
            app.btnBackImage = uibutton(app.ImagesTab, 'push');
            app.btnBackImage.ButtonPushedFcn = createCallbackFcn(app, @btnBackDeviceButtonPushed, true);
            app.btnBackImage.Text = 'Back';
            app.btnHLDImage = uibutton(app.ImagesTab, 'push');
            app.btnHLDImage.ButtonPushedFcn = createCallbackFcn(app, @btnHLDImageButtonPushed, true);
            app.btnHLDImage.Text = 'HLD';
            app.userPanel_2 = uipanel(app.ImagesTab);
            app.lblUser_2 = uilabel(app.userPanel_2);
            app.lblUser_2.Text = 'User Admin';
            app.SignedinasLabel_2 = uilabel(app.userPanel_2);
            app.SignedinasLabel_2.Text = 'Signed in as: ';
            app.Image_2 = uiimage(app.userPanel_2);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'src', 'icon-admin.png');
            app.DeviceDropDownLabel = uilabel(app.ImagesTab);
            app.DeviceDropDownLabel.Text = 'Device';
            app.dpdDevice = uidropdown(app.ImagesTab);
            app.dpdDevice.Items = {};
            app.dpdDevice.ValueChangedFcn = createCallbackFcn(app, @dpdDeviceValueChanged, true);
            app.dpdDevice.Value = {};
            app.SpinnerLabel = uilabel(app.ImagesTab);
            app.SpinnerLabel.Visible = 'off';
            app.SpinnerLabel.Text = 'Processing... 1/4';
            app.Spinner = uiimage(app.ImagesTab);
            app.Spinner.Visible = 'off';
            app.Spinner.ImageSource = fullfile(pathToMLAPP, 'src', 'Spinner.gif');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'src', 'utsjr_icon.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'src', 'SCI-H.png');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'src', 'cicata_icon.png');
            app.lblWarning = uilabel(app.UIFigure);
            app.lblWarning.Text = 'It is necessary have at least 20 reference images on 2 devices to have better fidelity';
            app.imgWarning = uiimage(app.UIFigure);
            app.imgWarning.ImageSource = fullfile(pathToMLAPP, 'src', 'warning.png');
            app.lblAlert = uilabel(app.UIFigure);
            app.lblAlert.Text = 'To obtain a better result it is recommended to have at least 30 reference images per device';
            app.imgAlert = uiimage(app.UIFigure);
            app.imgAlert.ImageSource = fullfile(pathToMLAPP, 'src', 'alert.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = device_register(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end