classdef login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figLogin                  matlab.ui.Figure
        imgUTSJR                  matlab.ui.control.Image
        imgCICATA                 matlab.ui.control.Image
        Panel                     matlab.ui.container.Panel
        PasswordEditFieldLabel_2  matlab.ui.control.Label
        Image3                    matlab.ui.control.Image
        Image2                    matlab.ui.control.Image
        UserEditFieldLabel        matlab.ui.control.Label
        lblDescription            matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        btnLogin                  matlab.ui.control.Button
        PasswordHTML              matlab.ui.control.HTML
        txtUser                   matlab.ui.control.EditField
        lblWelcome                matlab.ui.control.Label
        lblVersion                matlab.ui.control.Label
    end

    
    properties (Access = private)
        conn % Connection DB
        user % Username
        pass % Password
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create the connection with the ODBC connector previously created database in "databaseExplorer"
            app.conn = database('conn_scih', 'uqrpef8uvj0gk5k7', 'hS0C86ybuxlTFNVYSSto');
            addpath(strcat(pwd,'\modules\Hash'));
        end

        % Button pushed function: btnLogin
        function btnLoginButtonPushed(app, event)
            % Sets to app.user property the value by txtUser
            app.user = app.txtUser.Value;
            % Check if the user contains characters
            if app.user ~= ""
                % Check if the pass contains characters
                if app.pass ~= ""
                    % Prepare query statement with user condition
                    query = strcat("SELECT password, type FROM User ", ...
                        "WHERE username = '", app.user, "'");
                    % Execute fetch with the connection and query
                    try
                        rslt = fetch(app.conn, query);
                    catch e
                        msgbox(strcat("Error", char(e.message)), "Warning", "error");
                    end
                    if height(rslt.password) == 1
                        % Encriptar pass
                        passHash = string(DataHash(app.pass, 'SHA-512'));
                        % Password check in DB with password typed by user
                        if string(rslt.password) == string(passHash) % Use the pass property 
                            % User type check
                            switch string(rslt.type)
                                case "admin"
                                    % Open a new form with the parameter
                                    % User
                                    admin_view(app.user); 
                                    % Close this form
                                    delete(app);
                                case "basic"
                                    % Open a new form with the parameter
                                    % User
                                    basic_view(app.user); 
                                    % Close this form
                                    delete(app);
                            end
                        else
                            % Display a message indicating that an incorrect 
                            % password was entered
                            msgbox("Incorrect Password", "Error", "error");
                        end
                    else
                        % Display a message indicating that the user doesn't 
                        % exist
                        msgbox("The user doesn't exist", "Error", "error");
                    end
                else
                    % Display a message indicating that the password don't
                    % provided
                    msgbox("No password provided", "Warning", "warn");
                end
            else
                % Display a message indicating that the user don't
                % provided
                msgbox("No username provided", "Warning", "warn");
            end
            
            
        end

        % Callback function
        function ExitButtonPushed(app, event)
            % Close this form
            delete(app);
        end

        % Data changed function: PasswordHTML
        function PasswordHTMLDataChanged(app, event)
            % Save the passwort in the propertie app.pass
            app.pass = app.PasswordHTML.Data;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figLogin = uifigure('Visible', 'off');
            app.figLogin.Name = 'Login';
            app.lblVersion = uilabel(app.figLogin);
            app.lblVersion.Text = 'V1.0 10/11/2022';
            app.Panel = uipanel(app.figLogin);
            app.lblWelcome = uilabel(app.Panel);
            app.lblWelcome.Text = 'Welcome';
            app.txtUser = uieditfield(app.Panel, 'text');
            app.txtUser.Placeholder = 'User';
            app.PasswordHTML = uihtml(app.Panel);
            app.PasswordHTML.HTMLSource = 'passwordEdit.html';
            app.PasswordHTML.DataChangedFcn = createCallbackFcn(app, @PasswordHTMLDataChanged, true);
            app.btnLogin = uibutton(app.Panel, 'push');
            app.btnLogin.ButtonPushedFcn = createCallbackFcn(app, @btnLoginButtonPushed, true);
            app.btnLogin.Text = 'Log into my account';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'src', 'SCI-H.png');
            app.lblDescription = uilabel(app.Panel);
            app.lblDescription.Text = 'Source camera identification by Hellinger distance';
            app.UserEditFieldLabel = uilabel(app.Panel);
            app.UserEditFieldLabel.Text = 'User';
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'src', 'icon-basic.png');
            app.Image3 = uiimage(app.Panel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'src', 'icon-password.png');
            app.PasswordEditFieldLabel_2 = uilabel(app.Panel);
            app.PasswordEditFieldLabel_2.Text = 'Password';
            app.imgCICATA = uiimage(app.figLogin);
            app.imgCICATA.ImageSource = fullfile(pathToMLAPP, 'src', 'cicata_icon.png');
            app.imgUTSJR = uiimage(app.figLogin);
            app.imgUTSJR.ImageSource = fullfile(pathToMLAPP, 'src', 'utsjr_icon.png');
            % Show the figure after all components are created
            app.figLogin.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figLogin)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figLogin)
        end
    end
end