classdef user_register < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image2_2                matlab.ui.control.Image
        PanelRules              matlab.ui.container.Panel
        Rule4                   matlab.ui.control.Label
        Rule3                   matlab.ui.control.Label
        Rule2                   matlab.ui.control.Label
        Rule1                   matlab.ui.control.Label
        RulesLabel              matlab.ui.control.Label
        Title                   matlab.ui.control.Label
        Image3                  matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        btnBack                 matlab.ui.control.Button
        btnHDL                  matlab.ui.control.Button
        Panel                   matlab.ui.container.Panel
        EmailEditFieldLabel_2   matlab.ui.control.Label
        Panel_2                 matlab.ui.container.Panel
        Image5                  matlab.ui.control.Image
        Image4                  matlab.ui.control.Image
        SignedinasLabel         matlab.ui.control.Label
        lblUser                 matlab.ui.control.Label
        EmailEditFieldLabel     matlab.ui.control.Label
        FullnameEditFieldLabel  matlab.ui.control.Label
        btnRegister             matlab.ui.control.Button
        bgUserType              matlab.ui.container.ButtonGroup
        opBasic                 matlab.ui.control.RadioButton
        opAdmin                 matlab.ui.control.RadioButton
        UsernameEditFieldLabel  matlab.ui.control.Label
        PasswordHTML            matlab.ui.control.HTML
        txtEmail                matlab.ui.control.EditField
        txtUsername             matlab.ui.control.EditField
        txtName                 matlab.ui.control.EditField
        lblTitle                matlab.ui.control.Label
    end

    
    properties (Access = private)
        user % Description
        username % Description
        password % Description
        email % Description
        name % Description
        type % Description
        newUser % Description
        conn % Connector ODBC
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, user)
            % Set the user in the property
            app.user = user;
            % Show the user in the label
            app.lblUser.Text  = app.user;
            % Create the connection to DB
            app.conn = database('conn_scih', 'uqrpef8uvj0gk5k7', 'hS0C86ybuxlTFNVYSSto');
            % Add path
            addpath(strcat(pwd,'\modules\Hash'));
        end

        % Button pushed function: btnBack
        function btnBackButtonPushed(app, event)
            % Open the last view and send the user
            admin_view(app.user);
            % Close this form
            delete(app);
        end

        % Button pushed function: btnRegister
        function btnRegisterButtonPushed(app, event)
            % Declare numbers as strings
            numbers = ["0","1","2","3","4","5","6","7","8","9"];
            uppercases = ["A","B","C","D","E","F","G","H","I","J","K","L","M","N","Ñ","O","P","Q","R","S","T","U","V","X","Y","Z"];
            % True / False, depending on the result of the function contains
            % If password contains any of the values of numbers then tf = 1
            tfN = contains(app.password,numbers);
            tfUC = contains(app.password,uppercases);

            % Set the username of the TxtBox in the variable
            app.username = app.txtUsername.Value;
            % Set the email of the TxtBox in the variable
            app.email = app.txtEmail.Value;
            % Set the name of the TxtBox in the variable
            app.name = app.txtName.Value;
            % Validate if the parameters arent empty
            if app.name ~= ""
                if app.username ~= ""
                    % Validate the lenght of the username
                    if length(app.username) > 5
                        % Search the username in database
                        query = strcat("SELECT name FROM User ", ...
                            "WHERE username = '", app.username, "'");
                        rslt = fetch(app.conn, query);
                        % If the username doesnt exists create a new user
                        if height(rslt.name) ~= 1
                            if app.email ~= ""
                                % Validate if contains "@" and a dot 
                                if contains(app.email, "@") && contains(app.email, ".")
                                    % Validate the lenght of the password
                                    if length(app.password) > 8
                                        % Validate if the password contains
                                        % number and uppercase
                                        if tfN
                                            if tfUC
                                                 % Validate the lenght of the password
                                                if length(app.password) > 8
                                                    % Assign the type of
                                                    % account
                                                    if app.opAdmin.Value
                                                        app.type = "admin";
                                                    else
                                                        app.type = "basic";
                                                    end
                                                    % Create the hash of
                                                    % the password
                                                    passHash = string(DataHash(app.password, 'SHA-512'));
                                                    % Create the table to
                                                    % write in DB
                                                    app.newUser = table(string(app.username), string(passHash), string(app.type), string(app.name), string(app.email), ...
                                                        'VariableNames', {'username', 'password', 'type', 'name', 'email'});
                                                    try
                                                        sqlwrite(app.conn, 'User', app.newUser);
                                                        msgbox("Registered user successfully", "Information", "help");
                                                        app.txtUsername.Value = "";
                                                        app.PasswordHTML.Data = "";
                                                        app.txtEmail.Value = "";
                                                        app.txtName.Value = "";
                                                    catch e
                                                        msgbox(strcat("Error", char(e.message)), "Warning SQL WRITE", "error");
                                                    end
                                                else
                                                    msgbox("Password must be greater than 8 characters", "Error", "error");
                                                end
                                            else
                                                msgbox("Password must contain uppercase letters", "Error", "error");

                                            end
                                        else
                                            msgbox("Password must contain at least one number", "Error", "error");

                                        end
                                    else
                                        msgbox("Password must be greater than 8 characters", "Warning", "warn");
                                    end
                                else
                                    msgbox("Please insert a valid email", "Warning", "warn");
                                end
                            else
                                msgbox("No email provided", "Warning", "warn");
                            end
                        else
                            msgbox("The username is being used", "Warning", "warn");
                        end
                    else
                        msgbox("Username must be greater than 5 characters", "Error", "error");
                    end
                else
                    msgbox("No username provided", "Warning", "warn");
                end
            else
                msgbox("Enter your full name", "Warning", "warn");
            end
        end

        % Data changed function: PasswordHTML
        function PasswordHTMLDataChanged(app, event)
            % Change the value of the password in the property app.password
            app.password = app.PasswordHTML.Data;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.lblTitle = uilabel(app.Panel);
            app.lblTitle.Text = 'Register user';
            app.txtName = uieditfield(app.Panel, 'text');
            app.txtUsername = uieditfield(app.Panel, 'text');
            app.txtEmail = uieditfield(app.Panel, 'text');
            app.PasswordHTML = uihtml(app.Panel);
            app.PasswordHTML.HTMLSource = 'passwordEdit.html';
            app.PasswordHTML.DataChangedFcn = createCallbackFcn(app, @PasswordHTMLDataChanged, true);
            app.UsernameEditFieldLabel = uilabel(app.Panel);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.bgUserType = uibuttongroup(app.Panel);
            app.bgUserType.Title = 'User type';
            app.opAdmin = uiradiobutton(app.bgUserType);
            app.opAdmin.Text = 'Admin';
            app.opAdmin.Value = true;
            app.opBasic = uiradiobutton(app.bgUserType);
            app.opBasic.Text = 'Basic';
            app.btnRegister = uibutton(app.Panel, 'push');
            app.btnRegister.ButtonPushedFcn = createCallbackFcn(app, @btnRegisterButtonPushed, true);
            app.btnRegister.Text = 'Register';
            app.FullnameEditFieldLabel = uilabel(app.Panel);
            app.FullnameEditFieldLabel.Text = 'Full name';
            app.EmailEditFieldLabel = uilabel(app.Panel);
            app.EmailEditFieldLabel.Text = 'E-mail';
            app.Panel_2 = uipanel(app.Panel);
            app.lblUser = uilabel(app.Panel_2);
            app.lblUser.Text = 'User Admin';
            app.SignedinasLabel = uilabel(app.Panel_2);
            app.SignedinasLabel.Text = 'Signed in as: ';
            app.Image4 = uiimage(app.Panel_2);
            app.Image5 = uiimage(app.Panel_2);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'src', 'icon-admin.png');
            app.EmailEditFieldLabel_2 = uilabel(app.Panel);
            app.EmailEditFieldLabel_2.Text = 'Password';
            app.btnHDL = uibutton(app.UIFigure, 'push');
            app.btnHDL.Text = 'HLD';
            app.btnBack = uibutton(app.UIFigure, 'push');
            app.btnBack.ButtonPushedFcn = createCallbackFcn(app, @btnBackButtonPushed, true);
            app.btnBack.Text = 'Back';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'src', 'cicata_icon.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'src', 'SCI-H.png');
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Source camera identification by Hellinger distance';
            app.PanelRules = uipanel(app.UIFigure);
            app.RulesLabel = uilabel(app.PanelRules);
            app.RulesLabel.Text = 'Rules';
            app.Rule1 = uilabel(app.PanelRules);
            app.Rule1.Text = '-. Username must be greater than 5 characters';
            app.Rule2 = uilabel(app.PanelRules);
            app.Rule2.Text = '-. Password must be greater than 8 characters';
            app.Rule3 = uilabel(app.PanelRules);
            app.Rule3.Text = '-. Password must contain at least one number';
            app.Rule4 = uilabel(app.PanelRules);
            app.Rule4.Text = '-. Password must contain uppercase letters';
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'src', 'utsjr_icon.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = user_register(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end