classdef view_pdf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Image2_2         matlab.ui.control.Image
        lblShow          matlab.ui.control.Label
        swtType          matlab.ui.control.Switch
        TYPELabel        matlab.ui.control.Label
        swtImages        matlab.ui.control.Switch
        Image2           matlab.ui.control.Image
        Image4           matlab.ui.control.Image
        btnBack          matlab.ui.control.Button
        Panel            matlab.ui.container.Panel
        lblFile          matlab.ui.control.Label
        lblType          matlab.ui.control.Label
        tbPDF            matlab.ui.control.Table
        imgShow          matlab.ui.control.Image
        userPanel        matlab.ui.container.Panel
        Image            matlab.ui.control.Image
        SignedinasLabel  matlab.ui.control.Label
        lblUser          matlab.ui.control.Label
    end

    
    properties (Access = private)
        user % Description
        conn % Connection
        queryPDF % Query to get PDF's
        rsltPdf
        statImg
        indxSel
        typeUsr
    end
    
    methods (Access = private)
        % Function that 
        function updateTable(app)
            % Execute the query and get the results in app.devices
            try 
                app.rsltPdf = fetch(app.conn, app.queryPDF);
            catch e
                msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
            end
            % Insert the values of app.devices into tbDevices
            app.tbPDF.Data = app.rsltPdf;
        end

        function showImg(app)
            if app.indxSel ~= 0
                disp(app.swtType.Value)
                % Check the switch 
                if string(app.swtType.Value) == "Images"
                    app.imgShow.ImageSource = strcat(string(app.rsltPdf.path(app.indxSel)), string(app.rsltPdf.file(app.indxSel)));
                    app.lblFile.Text = string(app.rsltPdf.file(app.indxSel));
                else
                    app.imgShow.ImageSource = strcat(string(app.rsltPdf.path(app.indxSel)), "PDF\", strtok(string(app.rsltPdf.file(app.indxSel)), "."), "_rho.jpg");
                    app.lblFile.Text = strcat(strtok(string(app.rsltPdf.file(app.indxSel)), "."), "_rho.jpg");
                end   
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, user, type)
            % Create the connection with database
            app.conn = database('conn_scih', 'uqrpef8uvj0gk5k7', 'hS0C86ybuxlTFNVYSSto');
            % Save the parameter user in the propertie app.user
            app.user = "root";
            app.typeUsr = "admin";
            if app.typeUsr == "basic"
                app.swtImages.Visible = "off";
                app.lblShow.Visible = "off";
                try
                    rslt = fetch(app.conn, strcat("SELECT idUser FROM User WHERE username = '", app.user, "'"));
                    app.queryPDF = strcat("SELECT idQImage, file, path FROM DisputeImage WHERE HLDanalysis = 1 AND idUser = ", string(rslt.idUser));
                    app.lblType.Text = "Dispute";
                catch e 
                    msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
                end
            end
            % Show the user in the label    
            app.lblUser.Text = app.user;
            
            app.queryPDF = "SELECT file, path FROM ReferenceImage WHERE HLDanalysis = 1";
            app.updateTable();
            app.indxSel = 0;
        end

        % Button pushed function: btnBack
        function btnBackButtonPushed(app, event)
            % Return to login form
            admin_view(app.user);
            % Close this form
            delete(app);
        end

        % Value changed function: swtImages
        function swtImagesValueChanged(app, event)
            app.indxSel = 0;
            % Check the switch 
            if string(app.swtImages.Value) == "Reference"
                % Prepare the statement to get
                app.queryPDF = "SELECT idRImage, file, path FROM ReferenceImage WHERE HLDanalysis = 1";
                app.lblType.Text = "Reference";
                app.statImg = "RefPDF";
            else
                % Prepare the statement 
                app.queryPDF = "SELECT idQImage, file, path FROM DisputeImage WHERE HLDanalysis = 1";
                app.lblType.Text = "Dispute";
                app.statImg = "DisPDF";
            end    
            % 
            app.updateTable();
            app.imgShow.ImageSource = "";
        end

        % Cell selection callback: tbPDF
        function tbPDFCellSelection(app, event)
            app.indxSel = event.Indices(1);
            app.showImg();
        end

        % Value changed function: swtType
        function swtTypeValueChanged(app, event)
            app.showImg();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.userPanel = uipanel(app.Panel);
            app.lblUser = uilabel(app.userPanel);
            app.lblUser.Text = 'User Admin';
            app.SignedinasLabel = uilabel(app.userPanel);
            app.SignedinasLabel.Text = 'Signed in as: ';
            app.Image = uiimage(app.userPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'src', 'icon-admin.png');
            app.imgShow = uiimage(app.Panel);
            app.tbPDF = uitable(app.Panel);
            app.tbPDF.ColumnName = {'idImage'; 'Path'; 'File'};
            app.tbPDF.RowName = {};
            app.tbPDF.CellSelectionCallback = createCallbackFcn(app, @tbPDFCellSelection, true);
            app.lblType = uilabel(app.Panel);
            app.lblType.Text = 'Reference PDF''s';
            app.lblFile = uilabel(app.Panel);
            app.lblFile.Text = '';
            app.btnBack = uibutton(app.UIFigure, 'push');
            app.btnBack.ButtonPushedFcn = createCallbackFcn(app, @btnBackButtonPushed, true);
            app.btnBack.Text = 'Back';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'src', 'cicata_icon.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'src', 'SCI-H.png');
            app.swtImages = uiswitch(app.UIFigure, 'slider');
            app.swtImages.Items = {'Reference', 'Dispute'};
            app.swtImages.ValueChangedFcn = createCallbackFcn(app, @swtImagesValueChanged, true);
            app.swtImages.Value = 'Reference';
            app.TYPELabel = uilabel(app.UIFigure);
            app.TYPELabel.Text = 'TYPE';
            app.swtType = uiswitch(app.UIFigure, 'slider');
            app.swtType.Items = {'Images', 'PDF'};
            app.swtType.ValueChangedFcn = createCallbackFcn(app, @swtTypeValueChanged, true);
            app.swtType.Value = 'Images';
            app.lblShow = uilabel(app.UIFigure);
            app.lblShow.Tag = 'lblShow';
            app.lblShow.Text = 'SHOW';
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'src', 'utsjr_icon.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = view_pdf(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end